classdef bearingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        OpenMenu                        matlab.ui.container.Menu
        ExitMenu                        matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        SummaryTab                      matlab.ui.container.Tab
        UIAxes2                         matlab.ui.control.UIAxes
        GraphTab                        matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        FaultFrequenciesPanel           matlab.ui.container.Panel
        BallEditFieldLabel              matlab.ui.control.Label
        BallEditField                   matlab.ui.control.NumericEditField
        CageEditFieldLabel              matlab.ui.control.Label
        CageEditField                   matlab.ui.control.NumericEditField
        InnerEditFieldLabel             matlab.ui.control.Label
        InnerEditField                  matlab.ui.control.NumericEditField
        OuterEditFieldLabel             matlab.ui.control.Label
        OuterEditField                  matlab.ui.control.NumericEditField
        RevEditFieldLabel               matlab.ui.control.Label
        RevEditField                    matlab.ui.control.NumericEditField
        SetupTab                        matlab.ui.container.Tab
        BearingSetupPanel               matlab.ui.container.Panel
        RollerDiameterEditFieldLabel    matlab.ui.control.Label
        RollerDiameterEditField         matlab.ui.control.NumericEditField
        ContactAngleDegreeEditFieldLabel  matlab.ui.control.Label
        ContactAngleDegreeEditField     matlab.ui.control.NumericEditField
        NumberofElementsEditFieldLabel  matlab.ui.control.Label
        NumberofElementsEditField       matlab.ui.control.NumericEditField
        SideDropDownLabel               matlab.ui.control.Label
        SideDropDown                    matlab.ui.control.DropDown
        PitchDiameterEditFieldLabel     matlab.ui.control.Label
        PitchDiameterEditField          matlab.ui.control.NumericEditField
        AnalyzerSetupPanel              matlab.ui.container.Panel
        ThresholdEditFieldLabel         matlab.ui.control.Label
        ThresholdEditField              matlab.ui.control.NumericEditField
        MinEditFieldLabel               matlab.ui.control.Label
        MinEditField                    matlab.ui.control.NumericEditField
        MaxEditFieldLabel               matlab.ui.control.Label
        MaxEditField                    matlab.ui.control.NumericEditField
        SampleSetupPanel                matlab.ui.container.Panel
        SampleRateEditFieldLabel        matlab.ui.control.Label
        SampleRateEditField             matlab.ui.control.NumericEditField
        LoadEditFieldLabel              matlab.ui.control.Label
        LoadEditField                   matlab.ui.control.NumericEditField
        ShaftRateEditFieldLabel         matlab.ui.control.Label
        ShaftRateEditField              matlab.ui.control.NumericEditField
        DataDropDownLabel               matlab.ui.control.Label
        DataDropDown                    matlab.ui.control.DropDown
        CommandsPanel                   matlab.ui.container.Panel
        AnalyzeButton                   matlab.ui.control.Button
        LoadDataButton                  matlab.ui.control.Button
        StatusLampLabel                 matlab.ui.control.Label
        StatusLamp                      matlab.ui.control.Lamp
        LoadSamplesButton               matlab.ui.control.Button
    end


    properties (Access = private)
        fullFileName
        data
        samples
        info % Description
        fault
        images
        lamp
    end

    methods (Access = private)
        % https://www.mathworks.com/help/releases/R2016b/matlab/creating_guis/graphics-support-in-app-designer.html
        function plotBearingFault(app)
            reset(app.UIAxes);
            plot(app.UIAxes, app.fault.freq, app.fault.spec);
            ax = [xlim(app.UIAxes) ylim(app.UIAxes)];
            hold(app.UIAxes, 'on')
            for i = 1:5
                f = app.fault.freqs(i);
                plot(app.UIAxes, [f f], ax(3:4),'LineWidth',2)
            end
            plot(app.UIAxes, app.fault.peaks.loc, app.fault.peaks.val, 'o');
            hold(app.UIAxes, 'off')
            legend(app.UIAxes, 'env', 'cage', 'ball', 'outer', 'inner', '1/rev')
            xlabel(app.UIAxes, 'Hz')
            ylabel(app.UIAxes, 'Gs')
            xlim(app.UIAxes, [0 200]);
            ylim(app.UIAxes, ax(3:4));
        end

        function LampDisplay(app)
            if (app.fault.peaks.status(app.fault.peaks.status > 0))
                app.StatusLamp.Color = 'red';
                app.StatusLamp.Enable = 'on';
            else
                app.StatusLamp.Color = 'green';
                app.StatusLamp.Enable = 'on';
            end
        end
        
        function ImageDisplay(app)
            if (app.fault.peaks.status(3) > 0)
                app.images.top = imread('BallBearingOuterRace.png');
            elseif (app.fault.peaks.status(4) > 0)
                app.images.top = imread('BallBearingInnerRace.png');
            else
                app.images.top = imread('BallBearing.png');
            end
            imshow(app.images.top, 'Parent', app.UIAxes2);
        end

        function InitLamp(app)
            app.StatusLamp.Color = [0.65 0.65 0.65];
            app.StatusLamp.Enable = 'off';
        end
        
        function InitImage(app)
            app.images.top = imread('BallBearing.png');
            imshow(app.images.top, 'Parent', app.UIAxes2);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.info = NiceBearing();
            app.RollerDiameterEditField.Value = app.info.rd;
            app.PitchDiameterEditField.Value = app.info.pd;
            app.ContactAngleDegreeEditField.Value = app.info.ca;
            app.NumberofElementsEditField.Value = app.info.ne;
            app.ThresholdEditField.Value = app.info.thr;
            app.MinEditField.Value = app.info.min;
            app.MaxEditField.Value = app.info.max;
            InitLamp(app);
            InitImage(app);
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            % https://www.mathworks.com/help/matlab/creating_guis/simple-app-designer-example-apps.html
            app.fullFileName = fileLoad();
            InitLamp(app);
            InitImage(app);
        end

        % Callback function: ExitMenu, UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            app.fullFileName = fileLoad();
            InitLamp(app);
            InitImage(app);
                        
            if (app.fullFileName)
                % Load data file
                app.data = load(app.fullFileName);
                % Extract fields from data
                app.SampleRateEditField.Value = app.data.bearing.sr;
                if (ischar(app.data.bearing.load))
                    app.LoadEditField.Value = str2double(app.data.bearing.load);
                else
                    app.LoadEditField.Value = app.data.bearing.load;
                end
                app.ShaftRateEditField.Value = app.data.bearing.rate;
            end
        end

        % Callback function
        function AnalyzeDataButtonPushed(app, event)
            if (app.fullFileName)
                app.fault = calcFaultFreq(app.info, app.data.bearing);
            end
        end

        % Callback function
        function PlotResultsButtonPushed(app, event)
            if (app.fullFileName)
                plotBearingFault(app);
            end
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            if (numel(app.fullFileName) ~= 0 && app.data.bearing.rate ~= 0 && app.data.bearing.sr ~= 0)
                % Run calculations
                app.fault = calcFaultFreq(app.info, app.data.bearing);
                
                % Setup GUI fields
                app.CageEditField.Value = app.fault.freqs(1);
                app.BallEditField.Value = app.fault.freqs(2);
                app.OuterEditField.Value = app.fault.freqs(3);
                app.InnerEditField.Value = app.fault.freqs(4);
                app.RevEditField.Value = app.fault.freqs(5);
                
                % Bearing plot
                plotBearingFault(app);
                % Display bearing status
                LampDisplay(app);
                % Display bearing image
                ImageDisplay(app);
            end
        end

        % Value changed function: RollerDiameterEditField
        function RollerDiameterEditFieldValueChanged(app, event)
            value = app.RollerDiameterEditField.Value;
            app.info.rd = value;
        end

        % Value changed function: PitchDiameterEditField
        function PitchDiameterEditFieldValueChanged(app, event)
            value = app.PitchDiameterEditField.Value;
            app.info.pd = value;
        end

        % Value changed function: ContactAngleDegreeEditField
        function ContactAngleDegreeEditFieldValueChanged(app, event)
            value = app.ContactAngleDegreeEditField.Value;
            app.info.ca = value;
        end

        % Value changed function: NumberofElementsEditField
        function NumberofElementsEditFieldValueChanged(app, event)
            value = app.NumberofElementsEditField.Value;
            app.info.ne = value;
        end

        % Value changed function: SideDropDown
        function SideDropDownValueChanged(app, event)
            value = app.SideDropDown.Value;
            if strcmp(value, 'Inner')
                app.info.sd = 1;
            elseif strcmp(value, 'Outer')
                app.info.sd = 2;
            end
        end

        % Callback function
        function OpenFileButtonPushed(app, event)
            app.fullFileName = fileLoad();
            InitLamp(app);
            InitImage(app);
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            value = app.ThresholdEditField.Value;
            app.info.thr = value;
        end

        % Value changed function: MinEditField
        function MinEditFieldValueChanged(app, event)
            value = app.MinEditField.Value;
            app.info.min = value;
        end

        % Value changed function: MaxEditField
        function MaxEditFieldValueChanged(app, event)
            value = app.MaxEditField.Value;
            app.info.max = value;
        end

        % Callback function
        function LoadDataButtonPushed2(app, event)

        end

        % Button pushed function: LoadSamplesButton
        function LoadSamplesButtonPushed(app, event)
            app.fullFileName = fileLoad();
            InitLamp(app);
            InitImage(app);
            
            if (app.fullFileName)
                % Extract fields from data
                app.data.bearing.sr = app.SampleRateEditField.Value;
                app.data.bearing.load = app.LoadEditField.Value;
                app.data.bearing.rate = app.ShaftRateEditField.Value;
                % Load data file
                app.samples = load(app.fullFileName);
                app.DataDropDown.Items = fieldnames(app.samples)';
                app.data.bearing.gs = app.samples.(app.DataDropDown.Value);
            end            
        end

        % Value changed function: SampleRateEditField
        function SampleRateEditFieldValueChanged(app, event)
            value = app.SampleRateEditField.Value;
            app.data.bearing.sr = value;
        end

        % Value changed function: LoadEditField
        function LoadEditFieldValueChanged(app, event)
            value = app.LoadEditField.Value;
            app.data.bearing.load = value;
        end

        % Value changed function: ShaftRateEditField
        function ShaftRateEditFieldValueChanged(app, event)
            value = app.ShaftRateEditField.Value;
            app.data.bearing.rate = value;
        end

        % Value changed function: DataDropDown
        function DataDropDownValueChanged(app, event)
            value = app.DataDropDown.Value;
            app.data.bearing.gs = app.samples.(app.DataDropDown.Value);
            InitLamp(app);
            InitImage(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ExitMenu.Text = 'Exit';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SummaryTab = uitab(app.TabGroup);
            app.SummaryTab.Title = 'Summary';
            app.UIAxes2 = uiaxes(app.SummaryTab);
            app.GraphTab = uitab(app.TabGroup);
            app.GraphTab.Title = 'Graph';
            app.UIAxes = uiaxes(app.GraphTab);
            title(app.UIAxes, 'Bearing Analyzer')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.FaultFrequenciesPanel = uipanel(app.GraphTab);
            app.FaultFrequenciesPanel.Title = 'Fault Frequencies';
            app.BallEditFieldLabel = uilabel(app.FaultFrequenciesPanel);
            app.BallEditFieldLabel.Text = 'Ball';
            app.BallEditField = uieditfield(app.FaultFrequenciesPanel, 'numeric');
            app.CageEditFieldLabel = uilabel(app.FaultFrequenciesPanel);
            app.CageEditFieldLabel.Text = 'Cage';
            app.CageEditField = uieditfield(app.FaultFrequenciesPanel, 'numeric');
            app.InnerEditFieldLabel = uilabel(app.FaultFrequenciesPanel);
            app.InnerEditFieldLabel.Text = 'Inner';
            app.InnerEditField = uieditfield(app.FaultFrequenciesPanel, 'numeric');
            app.OuterEditFieldLabel = uilabel(app.FaultFrequenciesPanel);
            app.OuterEditFieldLabel.Text = 'Outer';
            app.OuterEditField = uieditfield(app.FaultFrequenciesPanel, 'numeric');
            app.RevEditFieldLabel = uilabel(app.FaultFrequenciesPanel);
            app.RevEditFieldLabel.Text = '1/Rev';
            app.RevEditField = uieditfield(app.FaultFrequenciesPanel, 'numeric');
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.BearingSetupPanel = uipanel(app.SetupTab);
            app.BearingSetupPanel.Title = 'Bearing Setup';
            app.RollerDiameterEditFieldLabel = uilabel(app.BearingSetupPanel);
            app.RollerDiameterEditFieldLabel.Text = 'Roller Diameter';
            app.RollerDiameterEditField = uieditfield(app.BearingSetupPanel, 'numeric');
            app.RollerDiameterEditField.ValueChangedFcn = createCallbackFcn(app, @RollerDiameterEditFieldValueChanged, true);
            app.ContactAngleDegreeEditFieldLabel = uilabel(app.BearingSetupPanel);
            app.ContactAngleDegreeEditFieldLabel.Text = 'Contact Angle (Degree)';
            app.ContactAngleDegreeEditField = uieditfield(app.BearingSetupPanel, 'numeric');
            app.ContactAngleDegreeEditField.ValueChangedFcn = createCallbackFcn(app, @ContactAngleDegreeEditFieldValueChanged, true);
            app.NumberofElementsEditFieldLabel = uilabel(app.BearingSetupPanel);
            app.NumberofElementsEditFieldLabel.Text = 'Number of Elements';
            app.NumberofElementsEditField = uieditfield(app.BearingSetupPanel, 'numeric');
            app.NumberofElementsEditField.ValueDisplayFormat = '%.0f';
            app.NumberofElementsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofElementsEditFieldValueChanged, true);
            app.SideDropDownLabel = uilabel(app.BearingSetupPanel);
            app.SideDropDownLabel.Text = 'Side';
            app.SideDropDown = uidropdown(app.BearingSetupPanel);
            app.SideDropDown.Items = {'Inner', 'Outer'};
            app.SideDropDown.ValueChangedFcn = createCallbackFcn(app, @SideDropDownValueChanged, true);
            app.SideDropDown.Value = 'Outer';
            app.PitchDiameterEditFieldLabel = uilabel(app.BearingSetupPanel);
            app.PitchDiameterEditFieldLabel.Text = 'Pitch Diameter';
            app.PitchDiameterEditField = uieditfield(app.BearingSetupPanel, 'numeric');
            app.PitchDiameterEditField.ValueChangedFcn = createCallbackFcn(app, @PitchDiameterEditFieldValueChanged, true);
            app.AnalyzerSetupPanel = uipanel(app.SetupTab);
            app.AnalyzerSetupPanel.Title = 'Analyzer Setup';
            app.ThresholdEditFieldLabel = uilabel(app.AnalyzerSetupPanel);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.AnalyzerSetupPanel, 'numeric');
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.MinEditFieldLabel = uilabel(app.AnalyzerSetupPanel);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.AnalyzerSetupPanel, 'numeric');
            app.MinEditField.ValueChangedFcn = createCallbackFcn(app, @MinEditFieldValueChanged, true);
            app.MaxEditFieldLabel = uilabel(app.AnalyzerSetupPanel);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.AnalyzerSetupPanel, 'numeric');
            app.MaxEditField.ValueChangedFcn = createCallbackFcn(app, @MaxEditFieldValueChanged, true);
            app.SampleSetupPanel = uipanel(app.SetupTab);
            app.SampleSetupPanel.Title = 'Sample Setup';
            app.SampleRateEditFieldLabel = uilabel(app.SampleSetupPanel);
            app.SampleRateEditFieldLabel.Text = 'Sample Rate';
            app.SampleRateEditField = uieditfield(app.SampleSetupPanel, 'numeric');
            app.SampleRateEditField.ValueDisplayFormat = '%.0f';
            app.SampleRateEditField.ValueChangedFcn = createCallbackFcn(app, @SampleRateEditFieldValueChanged, true);
            app.LoadEditFieldLabel = uilabel(app.SampleSetupPanel);
            app.LoadEditFieldLabel.Text = 'Load';
            app.LoadEditField = uieditfield(app.SampleSetupPanel, 'numeric');
            app.LoadEditField.ValueDisplayFormat = '%.0f';
            app.LoadEditField.ValueChangedFcn = createCallbackFcn(app, @LoadEditFieldValueChanged, true);
            app.ShaftRateEditFieldLabel = uilabel(app.SampleSetupPanel);
            app.ShaftRateEditFieldLabel.Text = 'Shaft Rate';
            app.ShaftRateEditField = uieditfield(app.SampleSetupPanel, 'numeric');
            app.ShaftRateEditField.ValueDisplayFormat = '%.0f';
            app.ShaftRateEditField.ValueChangedFcn = createCallbackFcn(app, @ShaftRateEditFieldValueChanged, true);
            app.DataDropDownLabel = uilabel(app.SampleSetupPanel);
            app.DataDropDownLabel.Text = 'Data';
            app.DataDropDown = uidropdown(app.SampleSetupPanel);
            app.DataDropDown.Items = {'gs'};
            app.DataDropDown.Editable = 'on';
            app.DataDropDown.ValueChangedFcn = createCallbackFcn(app, @DataDropDownValueChanged, true);
            app.DataDropDown.Value = 'gs';
            app.CommandsPanel = uipanel(app.UIFigure);
            app.CommandsPanel.Title = 'Commands';
            app.AnalyzeButton = uibutton(app.CommandsPanel, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.LoadDataButton = uibutton(app.CommandsPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.StatusLampLabel = uilabel(app.CommandsPanel);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.CommandsPanel);
            app.StatusLamp.Enable = 'off';
            app.LoadSamplesButton = uibutton(app.CommandsPanel, 'push');
            app.LoadSamplesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSamplesButtonPushed, true);
            app.LoadSamplesButton.Text = 'Load Samples';
        end
    end

    methods (Access = public)

        % Construct app
        function app = bearingApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end